classdef ENICalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ENIUIFigure                 matlab.ui.Figure
        CalculateButton             matlab.ui.control.Button
        V2rectangularLabel          matlab.ui.control.Label
        V2rectangularTextArea       matlab.ui.control.TextArea
        V3rectangularTextAreaLabel  matlab.ui.control.Label
        V3rectangularTextArea       matlab.ui.control.TextArea
        DropDownLabel               matlab.ui.control.Label
        DropDown                    matlab.ui.control.DropDown
        Bus2angleTextAreaLabel      matlab.ui.control.Label
        Bus2angleTextArea           matlab.ui.control.TextArea
        Bus3angleTextAreaLabel      matlab.ui.control.Label
        Bus3angleTextArea           matlab.ui.control.TextArea
        UIAxes                      matlab.ui.control.UIAxes
        IterationTextAreaLabel      matlab.ui.control.Label
        IterationTextArea           matlab.ui.control.TextArea
        TextArea                    matlab.ui.control.TextArea
        absV3TextAreaLabel          matlab.ui.control.Label
        absV3TextArea               matlab.ui.control.TextArea
        absV2TextAreaLabel          matlab.ui.control.Label
        absV2TextArea               matlab.ui.control.TextArea
    end

    
    methods (Access = private)
        
        function Gauss(app)
            
            num = xlsread('ee303FinalProjectTemplate.xlsx','BUS');
            %Identify Load P (real) and Q (imag) Values in per-unit
            pbase=100;
            p2=num(1,2)/pbase;
            q2=num(2,2)/pbase;
            
            p3=num(1,3)/pbase;
            q3=num(2,3)/pbase;
            v1=num(3,1)*cosd(num(4,1))+num(3,1)*sind(num(4,1))*1i;
            [num,txt,raw] = xlsread('ee303FinalProjectTemplate.xlsx','BRANCH');
            
            
            v2=1+0i;
            v3=1+0i;
            
            % impedance of transmission lines
            z12=str2double(raw(2,3));
            z13=str2double(raw(2,4));
            z23=str2double(raw(4,3));


            %converting impedances to Admitances
            y12=1/z12;
            y13=1/z13;
            y23=1/z23;
            
            %the Bus Admittance Matrix
            Y= [(y12+y13) -(y12) -(y13);
                -(y12) (y12+y23) -(y23);
                -(y13) -(y23) (y23+y13)];
          
            n=1;
            eps=0.01;
            %Vi=1/Yii[(Si*/Vi*)-Sum(Yik*Vk)]
            %e2 and e3 are the diference between the new value of v2 and v3
            %and and old value of v2 and v3 
            %em variable is assigned as the  difference or error value
            ite=1;
            while n > eps
                
                v2e = ((conj(p2-(q2*1i))/conj(v2))-y12*v1+y23*v3)/(y12+y23); 
                v3e = ((conj(p3-(q3*1i))/conj(v3))-y13*v1+y23*v2)/(y13+y23);
                e2 = abs(v2e - v2);
                e3 = abs(v3e - v3);
                v2 = v2e;
                v3 = v3e;
                em = [e2,e3];
                n = max(em);
                
                v2array(ite)=abs(v2);
                v3array(ite)=abs(v3);
                ite=ite+1;
            end
            v2array(ite)=abs(v2);
            v3array(ite)=abs(v3);
           
            title(app.UIAxes, 'V2 vs V3')
            xlabel(app.UIAxes, 'Abs(V2)')
            ylabel(app.UIAxes, 'Abs(V3)')
            plot(app.UIAxes,v2array,v3array,'--*r');
            ang2=180/pi*angle(v2);
            ang3=180/pi*angle(v3);
            app.V2rectangularTextArea.Value = sprintf('%f%+fi\n', real (v2), imag(v2));
            app.V3rectangularTextArea.Value = sprintf('%f%+fi\n', real (v3), imag(v3));
            app.absV2TextArea.Value = sprintf('%f',abs(v2));
            app.absV3TextArea.Value = sprintf('%f',abs(v3));
            app.Bus2angleTextArea.Value = sprintf('%f',ang2);
            app.Bus3angleTextArea.Value = sprintf('%f',ang3);
            app.IterationTextArea.Value = sprintf('%d',ite);
        end
        
        
        function NewtonRapson(app)
            
            % returns numeric and text data in num and txt 
            %, and string content in cell array raw
            [num] = xlsread('ee303FinalProjectTemplate.xlsx','BUS');
            
            %Identify Load P (real) and Q (imag) Values in per-unit
            Sbase=100;
            pd2=num(1,2)/Sbase;%real power of bus2
            qd2=num(2,2)/Sbase;%reactive power of bus2
            pd3=num(1,3)/Sbase;%real power of bus3
            qd3=num(2,3)/Sbase;%reactive power of bus3
            pg2=0;
            qg2=0;
            pg3=0;
            qg3=0;
            %calculate for each bus Real and Reactive Power
            P2=pd2-pg2;%bus2 PQ bus, there is no generator real power
            Q2=qd2-qg2;%and generator reactive power
            P3=pd3-pg3;%bus3 PQ bus, there is no generator real power
            Q3=qd3-qg3;%and generator reactive power
            
            
            v1=num(3,1)*cosd(num(4,1))+num(3,1)*sind(num(4,1))*1i;
            %initial guess for bus2 and bus3
            v2=1+0i;
            v3=1+0i;
            
            %reading excel sheet BRANCH
            [num,txt,raw] = xlsread('ee303FinalProjectTemplate.xlsx','BRANCH');
            
            %---------- impedance of transmission lines-------%
            %because in the raw we have string ,
            %we convert the data into the double
            z12=str2double(raw(2,3));
            z13=str2double(raw(2,4));
            z23=str2double(raw(4,3));
            z21=z12;
            z31=z13;
            z32=z23;
            
            
            %-------converting impedances to Admitances-----%
            y12=1/z12;
            y13=1/z13;
            y23=1/z23;
            
            %----------the Bus Admittance Matrix------%
            Y= [(y12+y13) -(y12) -(y13); 
                  -(y12) (y12+y23) -(y23); 
                  -(y13) -(y23) (y23+y13)];
              
              
            %----------Conductance Values------------%
            G(1,1)=real(Y(1,1)); 
            G(1,2)=real(Y(1,2)); 
            G(1,3)=real(Y(1,3)); 
            G(2,1)=real(Y(2,1)); 
            G(2,2)=real(Y(2,2)); 
            G(2,3)=real(Y(2,3)); 
            G(3,1)=real(Y(3,1)); 
            G(3,2)=real(Y(3,2)); 
            G(3,3)=real(Y(3,3));
            %--------Susceptance Values----------%
            B(1,1)=imag(Y(1,1)); 
            B(1,2)=imag(Y(1,2)); 
            B(1,3)=imag(Y(1,3));
            B(2,1)=imag(Y(2,1));
            B(2,2)=imag(Y(2,2));
            B(2,3)=imag(Y(2,3));
            B(3,1)=imag(Y(3,1));
            B(3,2)=imag(Y(3,2)); 
            B(3,3)=imag(Y(3,3));
            
            %--------- Given Specifications in pu (Known)----------%
            V1MAG=abs(v1);   
            ANG1=angle(v1); 
            %--------unknowns initial guess-----------%
            V2MAG=abs(v2);
            ANG2=angle(v2);
            V3MAG=abs(v3);
            ANG3=angle(v3);
            
            %we have 4 unknowns we will name them as x1,x2,x3,x4 as follows
            x1=ANG2;
            x2=ANG3;
            x3=V2MAG;
            x4=V3MAG;
            k=1;%iteration number
            epsilon=0.01;
            x = [x1;x2;x3;x4];
            f(1,1)=pd2-pg2;
                    f(2,1)=pd3-pg3;
                    f(3,1)=qd2-qg2;
                    f(4,1)=qd3-qg3;


            for i=1:6
                    
                %----------Calculating Jacobian------------%
                %J(1,1)=delP2/delANG2
                J(1,1)=x3*(V1MAG*(B(2,1)*cos(x1-ANG1)-G(2,1)*sin(x1-ANG1))+x4*(B(2,3)*cos(x1-x2)-G(2,3)*sin(x1-x2)));
                %J(1,2)=delP2/delx2 = J(1,2)
                J(1,2)=x3*x4*(G(2,3)*sin(x1-x2)-B(2,3)*cos(x1-x2));
                %J(1,3)=delP2/delx3
                J(1,3) =V1MAG*(G(2,1)*cos(x1- ANG1)+B(2,1)*sin(x1-ANG1))+2*x3*(G(2,2)*cos(x1-x1)+B(2,2)*sin(x1-x1))+x4*(G(2,3)*cos(x1-x2)+B(2,3)*sin(x1-x2));
                %J(1,4)=delP2/delx4
                J(1,4)=x3*(G(2,3)*cos(x1-x2)+B(2,3)*sin(x1-x2));
                %J(2,1)=delP3/delx1
                J(2,1)=x4*x3*(G(3,2)*sin(x2-x1)-B(3,2)*cos(x2-x1));
                %J(2,2)=delP3/delx2
                J(2,2)=x4*(V1MAG*(B(3,1)*cos(x2-ANG1)-G(3,1)*sin(x2-ANG1))+x3*(B(3,2)*cos(x2-x1)-G(3,2)*sin(x2-x1)));
                %J(2,3)=delP3/delx3
                J(2,3)=x4*(G(3,2)*cos(x2-x1)+B(3,2)*sin(x2-x1));
                %J(2,4)=delP3/delx4
                J(2,4)=V1MAG*(G(3,1)*cos(x2-ANG1))+x3*(G(3,2)*cos(x2-x1))+2*x4*(G(3,3)*cos(x2-x2));
                %J(3,1)delQ2/delx1
                J(3,1)=x3*V1MAG*(B(2,1)*sin(x1-ANG1)+G(2,1)*cos(x1-ANG1))+x3*x4*(B(2,3)*sin(x1-x2)+G(2,3)*cos(x1-x2));
                %J(3,2)=delQ2/delx2
                J(3,2)=x3*x4*(G(2,3)*cos(x1-x2)+B(2,3)*sin(x1-x2));
                %J(3,3)=delQ2/delx3
                J(3,3)=V1MAG*(G(2,1)*sin(x1-ANG1)-B(2,1)*cos(x1-ANG1))+2*x3*(G(2,2)*sin(x1-x1)-B(2,2)*cos(x1-x1))+x4*(G(2,3)*sin(x1-x2)-B(2,3)*cos(x1-x2));
                %J(3,4)=delQ2/delx4
                J(3,4)=x3*(G(2,3)*sin(x1-x2)-B(2,3)*cos(x1-x2));
                %J(4,1)=delQ3/delx1
                J(4,1)=x4*V1MAG*(G(3,1)*cos(x2-ANG1)+B(3,1)*sin(x2-ANG1))+x4*x3*(B(3,2)*sin(x2-x1)+G(3,2)*cos(x2-x1));
                %J(4,2)=delQ3/delx2
                J(4,2)=x4*V1MAG*(B(3,1)*sin(x2-ANG1)+G(3,1)*cos(x2-ANG1))+x4*x3*(B(3,2)*sin(x2-x1)+G(3,2)*cos(x2-x1))+x4*x4*(G(3,3)*cos(x2-x2)+B(3,3)*sin(x2-x2));
                %J(4,3)=delQ3/delx3
                J(4,3)=x4*(G(3,2)*cos(x2-x1)+B(3,2)*sin(x2-x1));
                %J(4,4)=delQ3/delx4
                J(4,4)=V1MAG*(G(3,1)*cos(x2-ANG1)+B(3,1)*sin(x2-ANG1))+x3*(G(3,2)*cos(x2-x1)+B(3,2)*sin(x2-x1))+2*x4*(G(3,3)*cos(x2-x2)+B(3,3)*sin(x2-x2));
            
                    
                    %we calculated the variables of vectors one by one and then assign them to
                    %f whcih is our f(x)
                    %--------f(x)-------------%
                    P2=x3*V1MAG*(G(2,1)*cos(x1-ANG1)+B(2,1)*sin(x1-ANG1))+(x3*x3*(G(2,2)*cos(x1-x1)+B(2,2)*sin(x1-x1)))+(x3*x4*(G(2,3)*cos(x1-x2)+B(2,3)*sin(x1-x2)));
                    P3=x4*V1MAG*(G(3,1)*cos(x2-ANG1)+B(3,1)*sin(x2-ANG1))+(x4*x3*(G(3,2)*cos(x2-x1)+B(3,2)*sin(x2-x1)))+(x4*x4*(G(3,3)*cos(x2-x2)+B(3,3)*sin(x2-x2)));
                    Q2=x3*V1MAG*(G(2,1)*sin(x1-ANG1)-B(2,1)*cos(x1-ANG1))+(x3*x3*(G(2,2)*sin(x1-x1)-B(2,2)*cos(x1-x1)))+(x3*x4*(G(2,3)*sin(x1-x2)-B(2,3)*cos(x1-x2)));
                    Q3=x4*V1MAG*(G(3,1)*sin(x2-ANG1)-B(3,1)*cos(x2-ANG1))+(x4*x3*(G(3,2)*sin(x2-x1)-B(3,2)*cos(x2-x1)))+(x4*x4*(G(3,3)*sin(x2-x2)-B(3,3)*cos(x2-x2)));
                    
                    delta_x=-inv(J)*f;
                    
                    f(1,1)=P2+f(1,1);
                    f(2,1)=P3+f(2,1);
                    f(3,1)=Q2+f(3,1);
                    f(4,1)=Q3+f(4,1);
                    %Calculate delta x
                       
            
                    %Calculate the next point of x
                        x=x+delta_x;
                        x1 = x(1);
                        x2 = x(2);
                        x3 = x(3);
                        x4 = x(4);
                        
                        v2array(k)=x3;
                        v3array(k)=x4;
                        k=k+1; 
                        
            end
                        v2array(k)=x3;
                        v3array(k)=x4;
                        title(app.UIAxes, 'V2 vs V3')
                        xlabel(app.UIAxes, 'Abs(V2)')
                        ylabel(app.UIAxes, 'Abs(V3)')
                        plot(app.UIAxes,v2array,v3array,'--*r');
                        
                        
                        app.V2rectangularTextArea.Value = sprintf('');
                        app.V3rectangularTextArea.Value = sprintf('');
                        app.absV2TextArea.Value = sprintf('%f',abs(x3));
                        app.absV3TextArea.Value = sprintf('%f',abs(x4));
                        app.Bus2angleTextArea.Value = sprintf('%f',x1);
                        app.Bus3angleTextArea.Value = sprintf('%f',x2);
                        app.IterationTextArea.Value = sprintf('%d',k);
                    end
            
        function DishonestNR(app)
                        
            % returns numeric and text data in num and txt 
            %, and string content in cell array raw
            [num] = xlsread('ee303FinalProjectTemplate.xlsx','BUS');
            
            %Identify Load P (real) and Q (imag) Values in per-unit
            Sbase=100;
            pd2=num(1,2)/Sbase;%real power of bus2
            qd2=num(2,2)/Sbase;%reactive power of bus2
            pd3=num(1,3)/Sbase;%real power of bus3
            qd3=num(2,3)/Sbase;%reactive power of bus3
            pg2=0;
            qg2=0;
            pg3=0;
            qg3=0;
            %calculate for each bus Real and Reactive Power
            P2=pd2-pg2;%bus2 PQ bus, there is no generator real power
            Q2=qd2-qg2;%and generator reactive power
            P3=pd3-pg3;%bus3 PQ bus, there is no generator real power
            Q3=qd3-qg3;%and generator reactive power


            v1=num(3,1)*cosd(num(4,1))+num(3,1)*sind(num(4,1))*1i;
            %initial guess for bus2 and bus3
            v2=1+0i;
            v3=1+0i;
            
            %reading excel sheet BRANCH
            [num,txt,raw] = xlsread('ee303FinalProjectTemplate.xlsx','BRANCH');
            
            %---------- impedance of transmission lines-------%
            %because in the raw we have string ,
            %we convert the data into the double
            z12=str2double(raw(2,3));
            z13=str2double(raw(2,4));
            z23=str2double(raw(4,3));
            z21=z12;
            z31=z13;
            z32=z23;
            
            
            %-------converting impedances to Admitances-----%
            y12=1/z12;
            y13=1/z13;
            y23=1/z23;
            
            %----------the Bus Admittance Matrix------%
            Y= [(y12+y13) -(y12) -(y13); 
                  -(y12) (y12+y23) -(y23); 
                  -(y13) -(y23) (y23+y13)];
              
              
            %----------Conductance Values------------%
            G(1,1)=real(Y(1,1)); 
            G(1,2)=real(Y(1,2)); 
            G(1,3)=real(Y(1,3)); 
            G(2,1)=real(Y(2,1)); 
            G(2,2)=real(Y(2,2)); 
            G(2,3)=real(Y(2,3)); 
            G(3,1)=real(Y(3,1)); 
            G(3,2)=real(Y(3,2)); 
            G(3,3)=real(Y(3,3));
            %--------Susceptance Values----------%
            B(1,1)=imag(Y(1,1)); 
            B(1,2)=imag(Y(1,2)); 
            B(1,3)=imag(Y(1,3));
            B(2,1)=imag(Y(2,1));
            B(2,2)=imag(Y(2,2));
            B(2,3)=imag(Y(2,3));
            B(3,1)=imag(Y(3,1));
            B(3,2)=imag(Y(3,2)); 
            B(3,3)=imag(Y(3,3));
            
            %--------- Given Specifications in pu (Known)----------%
            V1MAG=abs(v1);   
            ANG1=angle(v1); 
            %--------unknowns initial guess-----------%
            V2MAG=abs(v2);
            ANG2=angle(v2);
            V3MAG=abs(v3);
            ANG3=angle(v3);
            
            %we have 4 unknowns we will name them as x1,x2,x3,x4 as follows
            x1=ANG2;
            x2=ANG3;
            x3=V2MAG;
            x4=V3MAG;
            k=1;%iteration number
            epsilon=0.01;
            x = [x1;x2;x3;x4];
            
            %----------Calculating Jacobian------------%
                %J(1,1)=delP2/delANG2
                J(1,1)=x3*(V1MAG*(B(2,1)*cos(x1-ANG1)-G(2,1)*sin(x1-ANG1))+x4*(B(2,3)*cos(x1-x2)-G(2,3)*sin(x1-x2)));
                %J(1,2)=delP2/delx2 = J(1,2)
                J(1,2)=x3*x4*(G(2,3)*sin(x1-x2)-B(2,3)*cos(x1-x2));
                %J(1,3)=delP2/delx3
                J(1,3) =V1MAG*(G(2,1)*cos(x1- ANG1)+B(2,1)*sin(x1-ANG1))+2*x3*(G(2,2)*cos(x1-x1)+B(2,2)*sin(x1-x1))+x4*(G(2,3)*cos(x1-x2)+B(2,3)*sin(x1-x2));
                %J(1,4)=delP2/delx4
                J(1,4)=x3*(G(2,3)*cos(x1-x2)+B(2,3)*sin(x1-x2));
                %J(2,1)=delP3/delx1
                J(2,1)=x4*x3*(G(3,2)*sin(x2-x1)-B(3,2)*cos(x2-x1));
                %J(2,2)=delP3/delx2
                J(2,2)=x4*(V1MAG*(B(3,1)*cos(x2-ANG1)-G(3,1)*sin(x2-ANG1))+x3*(B(3,2)*cos(x2-x1)-G(3,2)*sin(x2-x1)));
                %J(2,3)=delP3/delx3
                J(2,3)=x4*(G(3,2)*cos(x2-x1)+B(3,2)*sin(x2-x1));
                %J(2,4)=delP3/delx4
                J(2,4)=V1MAG*(G(3,1)*cos(x2-ANG1))+x3*(G(3,2)*cos(x2-x1))+2*x4*(G(3,3)*cos(x2-x2));
                %J(3,1)delQ2/delx1
                J(3,1)=x3*V1MAG*(B(2,1)*sin(x1-ANG1)+G(2,1)*cos(x1-ANG1))+x3*x4*(B(2,3)*sin(x1-x2)+G(2,3)*cos(x1-x2));
                %J(3,2)=delQ2/delx2
                J(3,2)=x3*x4*(G(2,3)*cos(x1-x2)+B(2,3)*sin(x1-x2));
                %J(3,3)=delQ2/delx3
                J(3,3)=V1MAG*(G(2,1)*sin(x1-ANG1)-B(2,1)*cos(x1-ANG1))+2*x3*(G(2,2)*sin(x1-x1)-B(2,2)*cos(x1-x1))+x4*(G(2,3)*sin(x1-x2)-B(2,3)*cos(x1-x2));
                %J(3,4)=delQ2/delx4
                J(3,4)=x3*(G(2,3)*sin(x1-x2)-B(2,3)*cos(x1-x2));
                %J(4,1)=delQ3/delx1
                J(4,1)=x4*V1MAG*(G(3,1)*cos(x2-ANG1)+B(3,1)*sin(x2-ANG1))+x4*x3*(B(3,2)*sin(x2-x1)+G(3,2)*cos(x2-x1));
                %J(4,2)=delQ3/delx2
                J(4,2)=x4*V1MAG*(B(3,1)*sin(x2-ANG1)+G(3,1)*cos(x2-ANG1))+x4*x3*(B(3,2)*sin(x2-x1)+G(3,2)*cos(x2-x1))+x4*x4*(G(3,3)*cos(x2-x2)+B(3,3)*sin(x2-x2));
                %J(4,3)=delQ3/delx3
                J(4,3)=x4*(G(3,2)*cos(x2-x1)+B(3,2)*sin(x2-x1));
                %J(4,4)=delQ3/delx4
                J(4,4)=V1MAG*(G(3,1)*cos(x2-ANG1)+B(3,1)*sin(x2-ANG1))+x3*(G(3,2)*cos(x2-x1)+B(3,2)*sin(x2-x1))+2*x4*(G(3,3)*cos(x2-x2)+B(3,3)*sin(x2-x2));
                
                k=1;
                for i=1:2
                %we calculated the variables of vectors one by one and then assign them to
                    %f whcih is our f(x)
                    %--------f(x)-------------%
                    P2=x3*V1MAG*(G(2,1)*cos(x1-ANG1)+B(2,1)*sin(x1-ANG1))+x3*x3*(G(2,2)*cos(x1-x1)+B(2,2)*sin(x1-x1))+x3*x4*(G(2,3)*cos(x1-x2)+B(2,3)*sin(x1-x2));
                    P3=x4*V1MAG*(G(3,1)*cos(x2-ANG1)+B(3,1)*sin(x2-ANG1))+x4*x3*(G(3,2)*cos(x2-x1)+B(3,2)*sin(x2-x1))+x4*x4*(G(3,3)*cos(x2-x2)+B(3,3)*sin(x2-x2));
                    Q2=x3*V1MAG*(G(2,1)*sin(x1-ANG1)-B(2,1)*cos(x1-ANG1))+x3*x3*(G(2,2)*sin(x1-x1)-B(2,2)*cos(x1-x1))+x3*x4*(G(2,3)*sin(x1-x2)-B(2,3)*cos(x1-x2));
                    Q3=x4*V1MAG*(G(3,1)*sin(x2-ANG1)-B(3,1)*cos(x2-ANG1))+x4*x3*(G(3,2)*sin(x2-x1)-B(3,2)*cos(x2-x1))+x4*x4*(G(3,3)*sin(x2-x2)-B(3,3)*cos(x2-x2));
            
                    
                    f(1,1)=pd2-pg2;
                    f(2,1)=pd3-pg3;
                    f(3,1)=qd2-qg2;
                    f(4,1)=qd3-qg3;
                    %Calculate delta x
                        delta_x=-inv(J)*f;
            
                    %Calculate the next point of x
                        x=x+delta_x;
                        x1 = x(1);
                        x2 = x(2);
                        x3 = x(3);
                        x4 = x(4);
                        v2array(k)=x3;
                        v3array(k)=x4;
                        
                        k=k+1; 
                        
                end
                v2array(k)=abs(x3);
                        v3array(k)=abs(x4);

            
            title(app.UIAxes, 'V2 vs V3')
            xlabel(app.UIAxes, 'Abs(V2)')
            ylabel(app.UIAxes, 'Abs(V3)')
            plot(app.UIAxes,v2array,v3array,'--*r');
            
            
            app.V2rectangularTextArea.Value = sprintf('');
            app.V3rectangularTextArea.Value = sprintf('');
            app.absV2TextArea.Value = sprintf('%f',abs(x3));
            app.absV3TextArea.Value = sprintf('%f',abs(x4));
            app.Bus2angleTextArea.Value = sprintf('%f',x1);
            app.Bus3angleTextArea.Value = sprintf('%f',x2);
            app.IterationTextArea.Value = sprintf('%d',k);
        end
        
        function DecoupledPowerFlow(app);
            % returns numeric and text data in num and txt 
            %, and string content in cell array raw
            [num] = xlsread('ee303FinalProjectTemplate.xlsx','BUS');
            
            %Identify Load P (real) and Q (imag) Values in per-unit
            Sbase=100;
            pd2=num(1,2)/Sbase;%real power of bus2
            qd2=num(2,2)/Sbase;%reactive power of bus2
            pd3=num(1,3)/Sbase;%real power of bus3
            qd3=num(2,3)/Sbase;%reactive power of bus3
            pg2=0;
            qg2=0;
            pg3=0;
            qg3=0;
            %calculate for each bus Real and Reactive Power
            P2=pd2-pg2;%bus2 PQ bus, there is no generator real power
            Q2=qd2-qg2;%and generator reactive power
            P3=pd3-pg3;%bus3 PQ bus, there is no generator real power
            Q3=qd3-qg3;%and generator reactive power
            
            
            v1=num(3,1)*cosd(num(4,1))+num(3,1)*sind(num(4,1))*1i;
            %initial guess for bus2 and bus3
            v2=1+0i;
            v3=1+0i;
            
            %reading excel sheet BRANCH
            [num,txt,raw] = xlsread('ee303FinalProjectTemplate.xlsx','BRANCH');
            
            %---------- impedance of transmission lines-------%
            %because in the raw we have string ,
            %we convert the data into the double
            z12=str2double(raw(2,3));
            z13=str2double(raw(2,4));
            z23=str2double(raw(4,3));
            z21=z12;
            z31=z13;
            z32=z23;
            
            
            %-------converting impedances to Admitances-----%
            y12=1/z12;
            y13=1/z13;
            y23=1/z23;
            
            %----------the Bus Admittance Matrix------%
            Y= [(y12+y13) -(y12) -(y13); 
                  -(y12) (y12+y23) -(y23); 
                  -(y13) -(y23) (y23+y13)];
              
              
            %----------Conductance Values------------%
            G(1,1)=real(Y(1,1)); 
            G(1,2)=real(Y(1,2)); 
            G(1,3)=real(Y(1,3)); 
            G(2,1)=real(Y(2,1)); 
            G(2,2)=real(Y(2,2)); 
            G(2,3)=real(Y(2,3)); 
            G(3,1)=real(Y(3,1)); 
            G(3,2)=real(Y(3,2)); 
            G(3,3)=real(Y(3,3));
            %--------Susceptance Values----------%
            B(1,1)=imag(Y(1,1)); 
            B(1,2)=imag(Y(1,2)); 
            B(1,3)=imag(Y(1,3));
            B(2,1)=imag(Y(2,1));
            B(2,2)=imag(Y(2,2));
            B(2,3)=imag(Y(2,3));
            B(3,1)=imag(Y(3,1));
            B(3,2)=imag(Y(3,2)); 
            B(3,3)=imag(Y(3,3));
            
            %--------- Given Specifications in pu (Known)----------%
            V1MAG=abs(v1);   
            ANG1=angle(v1); 
            %--------unknowns initial guess-----------%
            V2MAG=abs(v2);
            ANG2=angle(v2);
            V3MAG=abs(v3);
            ANG3=angle(v3);
            
            %we have 4 unknowns we will name them as x1,x2,x3,x4 as follows
            x1=ANG2;
            x2=ANG3;
            x3=V2MAG;
            x4=V3MAG;
            k=1;%iteration number
            epsilon=0.01;
            x = [x1;x2;x3;x4];
            f(1,1)=pd2-pg2;
            f(2,1)=pd3-pg3;
            f(3,1)=qd2-qg2;
            f(4,1)=qd3-qg3;


            for i=1:6
                    
                %----------Calculating Jacobian------------%
                %J(1,1)=delP2/delANG2
                J(1,1)=x3*(V1MAG*(B(2,1)*cos(x1-ANG1)-G(2,1)*sin(x1-ANG1))+x4*(B(2,3)*cos(x1-x2)-G(2,3)*sin(x1-x2)));
                %J(1,2)=delP2/delx2 = J(1,2)
                J(1,2)=x3*x4*(G(2,3)*sin(x1-x2)-B(2,3)*cos(x1-x2));
                %J(1,3)=delP2/delx3
                J(1,3) =V1MAG*(G(2,1)*cos(x1- ANG1)+B(2,1)*sin(x1-ANG1))+2*x3*(G(2,2)*cos(x1-x1)+B(2,2)*sin(x1-x1))+x4*(G(2,3)*cos(x1-x2)+B(2,3)*sin(x1-x2));
                %J(1,4)=delP2/delx4
                %J(1,4)=x3*(G(2,3)*cos(x1-x2)+B(2,3)*sin(x1-x2));
                %we take it 0 because its value is verry small, so we do not take it
                J(1,4)=0;
                %J(2,1)=delP3/delx1
                J(2,1)=x4*x3*(G(3,2)*sin(x2-x1)-B(3,2)*cos(x2-x1));
                %J(2,2)=delP3/delx2
                J(2,2)=x4*(V1MAG*(B(3,1)*cos(x2-ANG1)-G(3,1)*sin(x2-ANG1))+x3*(B(3,2)*cos(x2-x1)-G(3,2)*sin(x2-x1)));
                %J(2,3)=delP3/delx3
                %J(2,3)=x4*(G(3,2)*cos(x2-x1)+B(3,2)*sin(x2-x1));
                %we take it 0 because its value is verry small, so we do not take it
                J(2,3)=0;
                %J(2,4)=delP3/delx4
                J(2,4)=V1MAG*(G(3,1)*cos(x2-ANG1))+x3*(G(3,2)*cos(x2-x1))+2*x4*(G(3,3)*cos(x2-x2));
                %J(3,1)delQ2/delx1
                J(3,1)=x3*V1MAG*(B(2,1)*sin(x1-ANG1)+G(2,1)*cos(x1-ANG1))+x3*x4*(B(2,3)*sin(x1-x2)+G(2,3)*cos(x1-x2));
                %J(3,2)=delQ2/delx2
                %J(3,2)=x3*x4*(G(2,3)*cos(x1-x2)+B(2,3)*sin(x1-x2));
                %we take it 0 because its value is verry small, so we do not take it
                J(3,2)=0;
                %J(3,3)=delQ2/delx3
                J(3,3)=V1MAG*(G(2,1)*sin(x1-ANG1)-B(2,1)*cos(x1-ANG1))+2*x3*(G(2,2)*sin(x1-x1)-B(2,2)*cos(x1-x1))+x4*(G(2,3)*sin(x1-x2)-B(2,3)*cos(x1-x2));
                %J(3,4)=delQ2/delx4
                J(3,4)=x3*(G(2,3)*sin(x1-x2)-B(2,3)*cos(x1-x2));
                %J(4,1)=delQ3/delx1
                %J(4,1)=x4*V1MAG*(G(3,1)*cos(x2-ANG1)+B(3,1)*sin(x2-ANG1))+x4*x3*(B(3,2)*sin(x2-x1)+G(3,2)*cos(x2-x1));
                %we take it 0 because its value is verry small, so we do not take it
                J(4,1)=0;
                %J(4,2)=delQ3/delx2
                J(4,2)=x4*V1MAG*(B(3,1)*sin(x2-ANG1)+G(3,1)*cos(x2-ANG1))+x4*x3*(B(3,2)*sin(x2-x1)+G(3,2)*cos(x2-x1))+x4*x4*(G(3,3)*cos(x2-x2)+B(3,3)*sin(x2-x2));
                %J(4,3)=delQ3/delx3
                J(4,3)=x4*(G(3,2)*cos(x2-x1)+B(3,2)*sin(x2-x1));
                %J(4,4)=delQ3/delx4
                J(4,4)=V1MAG*(G(3,1)*cos(x2-ANG1)+B(3,1)*sin(x2-ANG1))+x3*(G(3,2)*cos(x2-x1)+B(3,2)*sin(x2-x1))+2*x4*(G(3,3)*cos(x2-x2)+B(3,3)*sin(x2-x2));


                    %we calculated the variables of vectors one by one and then assign them to
                    %f whcih is our f(x)
                    %--------f(x)-------------%
                    P2=x3*V1MAG*(G(2,1)*cos(x1-ANG1)+B(2,1)*sin(x1-ANG1))+(x3*x3*(G(2,2)*cos(x1-x1)+B(2,2)*sin(x1-x1)))+(x3*x4*(G(2,3)*cos(x1-x2)+B(2,3)*sin(x1-x2)));
                    P3=x4*V1MAG*(G(3,1)*cos(x2-ANG1)+B(3,1)*sin(x2-ANG1))+(x4*x3*(G(3,2)*cos(x2-x1)+B(3,2)*sin(x2-x1)))+(x4*x4*(G(3,3)*cos(x2-x2)+B(3,3)*sin(x2-x2)));
                    Q2=x3*V1MAG*(G(2,1)*sin(x1-ANG1)-B(2,1)*cos(x1-ANG1))+(x3*x3*(G(2,2)*sin(x1-x1)-B(2,2)*cos(x1-x1)))+(x3*x4*(G(2,3)*sin(x1-x2)-B(2,3)*cos(x1-x2)));
                    Q3=x4*V1MAG*(G(3,1)*sin(x2-ANG1)-B(3,1)*cos(x2-ANG1))+(x4*x3*(G(3,2)*sin(x2-x1)-B(3,2)*cos(x2-x1)))+(x4*x4*(G(3,3)*sin(x2-x2)-B(3,3)*cos(x2-x2)));
                    %Calculate delta P and Q
                    delta_P(1,1)=P2;
                    delta_P(2,1)=P3;
                    delta_Q(1,1)=Q2;
                    delta_Q(2,1)=Q3;
                    
                    f(1,1)=P2+f(1,1);
                    f(2,1)=P3+f(2,1);
                    f(3,1)=Q2+f(3,1);
                    f(4,1)=Q3+f(4,1);
                    
                    
                    f=-J*x;
                    
                    x3array(k)=f(3,1);
                    x4array(k)=f(4,1);
                    %Calculate the next point of x
                        
                        k=k+1; 
            end
            x1=f(1,1);
            x2=f(2,1);
            x3=f(3,1);
            x4=f(4,1);
            x3array(k)=f(3,1);
            x4array(k)=f(4,1);
            title(app.UIAxes, 'V2 vs V3')
            xlabel(app.UIAxes, 'Abs(V2)')
            ylabel(app.UIAxes, 'Abs(V3)')
            plot(app.UIAxes,x3array,x4array,'--*r');
            
            
            app.V2rectangularTextArea.Value = sprintf('');
            app.V3rectangularTextArea.Value = sprintf('');
            app.absV2TextArea.Value = sprintf('%f',abs(x3));
            app.absV3TextArea.Value = sprintf('%f',abs(x4));
            app.Bus2angleTextArea.Value = sprintf('%f',x1);
            app.Bus3angleTextArea.Value = sprintf('%f',x2);
            app.IterationTextArea.Value = sprintf('%d',k);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            conversionValue = app.DropDown.Value;
            
            if(conversionValue == "Gauss-Seidel")
                Gauss(app);
            elseif (conversionValue == "Newton – Raphson")
                NewtonRapson(app);
            elseif (conversionValue == "Dishonest NR")
                DishonestNR(app);
                else (conversionValue == "Decoupled Power Flow");
                    DecoupledPowerFlow(app);
                
            end
            
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.TextArea.Value = sprintf('Please press calculate to solve the problem');
            app.V2rectangularTextArea.Value = sprintf('');
            app.V3rectangularTextArea.Value = sprintf('');
            app.Bus2angleTextArea.Value = sprintf('');
            app.Bus3angleTextArea.Value = sprintf('');
            app.IterationTextArea.Value = sprintf('');
            app.absV2TextArea.Value = sprintf('');
            app.absV3TextArea.Value = sprintf('');
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            plot(app.UIAxes,[0]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ENIUIFigure = uifigure('Visible', 'off');
            app.ENIUIFigure.Name = 'ENI';
            app.CalculateButton = uibutton(app.ENIUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.V2rectangularLabel = uilabel(app.ENIUIFigure);
            app.V2rectangularLabel.Text = 'V2 (rectangular)';
            app.V2rectangularTextArea = uitextarea(app.ENIUIFigure);
            app.V3rectangularTextAreaLabel = uilabel(app.ENIUIFigure);
            app.V3rectangularTextAreaLabel.Text = 'V3 (rectangular)';
            app.V3rectangularTextArea = uitextarea(app.ENIUIFigure);
            app.DropDownLabel = uilabel(app.ENIUIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.ENIUIFigure);
            app.DropDown.Items = {'Gauss-Seidel', 'Newton – Raphson', 'Dishonest NR', 'Decoupled Power Flow'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Gauss-Seidel';
            app.Bus2angleTextAreaLabel = uilabel(app.ENIUIFigure);
            app.Bus2angleTextAreaLabel.Text = 'Bus 2 angle';
            app.Bus2angleTextArea = uitextarea(app.ENIUIFigure);
            app.Bus3angleTextAreaLabel = uilabel(app.ENIUIFigure);
            app.Bus3angleTextAreaLabel.Text = 'Bus 3 angle';
            app.Bus3angleTextArea = uitextarea(app.ENIUIFigure);
            app.UIAxes = uiaxes(app.ENIUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.IterationTextAreaLabel = uilabel(app.ENIUIFigure);
            app.IterationTextAreaLabel.Text = 'Iteration';
            app.IterationTextArea = uitextarea(app.ENIUIFigure);
            app.TextArea = uitextarea(app.ENIUIFigure);
            app.TextArea.Value = {'Please select a method to solve the problem'};
            app.absV3TextAreaLabel = uilabel(app.ENIUIFigure);
            app.absV3TextAreaLabel.Text = 'abs(V3)';
            app.absV3TextArea = uitextarea(app.ENIUIFigure);
            app.absV2TextAreaLabel = uilabel(app.ENIUIFigure);
            app.absV2TextAreaLabel.Text = 'abs(V2) ';
            app.absV2TextArea = uitextarea(app.ENIUIFigure);
            % Show the figure after all components are created
            app.ENIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ENICalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ENIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ENIUIFigure)
        end
    end
end